classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditEvaluationButton    matlab.ui.control.Button
        UploadImageButton_2     matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        UploadImageButton       matlab.ui.control.Button
        ChangeImageButton       matlab.ui.control.Button
        ProceedButton           matlab.ui.control.Button
        ImageProcessed          matlab.ui.control.Image
        ImageRaw                matlab.ui.control.Image
        ImageNoiseReduced       matlab.ui.control.Image
        ImageAfterSegmentation  matlab.ui.control.Image
    end

    
    properties (Access = private)
        RawImageData %Raw Image
        DenoisedData %Denoised Image
        BinaryMask   %Region of Interest Identifier
        FinalOutput  %Final Image 
        BorderMask
    end


methods (Access = private)

        % --- Parameter Estimation Stage ---
        function nIters = autoEstimateDenoiseStrength(app, img)
            noiseVar = max(0, mean2(stdfilt(img).^2));
            nIters = round(min(12, max(3, noiseVar * 50)));
        end

        function sens = autoEstimateSensitivity(app, img)
            otsuLevel = graythresh(img);
            sens = min(0.7, max(0.3, otsuLevel * 1.1));
        end

        function [minArea, radius] = autoEstimateMorphParams(app, img)
            [numRows, numCols] = size(img);
            totalPixels = numRows * numCols;
            minArea = max(20, round(totalPixels * 0.0005));
            avgDim = mean([numRows, numCols]);
            radius = round(avgDim / 120);
        end

        % --- Core Processing Functions ---
        function denoisedImg = runDenoisingStage(app, inputImg, filterStrength)
            denoisedImg = imdiffusefilt(inputImg, ...
                'GradientThreshold', 0.1, ...
                'NumberOfIterations', filterStrength);
        end

        function segMask = runSegmentationStage(app, denoisedImg, sensitivity)
            segMask = imbinarize(denoisedImg, 'adaptive', ...
                'Sensitivity', sensitivity, ...
                'ForegroundPolarity', 'dark');
            segMask = segMask;
        end

        function cleanMask = runMorphologicalStage(app, binaryMask, minArea, radius)
            cleanMask = bwareaopen(binaryMask, minArea);
            se = strel('disk', max(1, radius));
            cleanMask = imclose(cleanMask, se);
            cleanMask = imfill(cleanMask, 'holes');
        end

        % --- Rendering & Display Handler ---
        function renderVisualResults(app)
            % 1. Create final masked output matrix
            app.FinalOutput = app.DenoisedData;
            app.FinalOutput(~app.BinaryMask) = 0;

            % 2. Assign raw image preview
            app.ImageRaw.ImageSource = app.matrixToImageSource(app.RawImageData);

            % 3. Assign noise reduced preview
            app.ImageNoiseReduced.ImageSource = app.matrixToImageSource(app.DenoisedData);

            % 4. Assign segmentation binary mask preview
            app.ImageAfterSegmentation.ImageSource = app.matrixToImageSource(double(app.BinaryMask));

            % 5. Create overlaid boundary view for the main Processed Image view
            overlayImg = app.createBoundaryOverlay(app.FinalOutput, app.BinaryMask);
            app.ImageProcessed.ImageSource = app.matrixToImageSource(overlayImg);
        end

        % --- Master Pipeline Execution ---
        function executeAutoPipeline(app)
            if isempty(app.RawImageData)
                return;
            end

            autoDenoiseIter = app.autoEstimateDenoiseStrength(app.RawImageData);
            autoSens        = app.autoEstimateSensitivity(app.RawImageData);
            [autoMinArea, autoRadius] = app.autoEstimateMorphParams(app.RawImageData);

            app.DenoisedData = app.runDenoisingStage(app.RawImageData, autoDenoiseIter);
            app.BinaryMask   = app.runSegmentationStage(app.DenoisedData, autoSens);
            app.BinaryMask   = app.BinaryMask & app.BorderMask;   % restrict to ROI here
            app.BinaryMask   = app.runMorphologicalStage(app.BinaryMask, autoMinArea, autoRadius);

            app.renderVisualResults();
        end

    function normImg = prepareRawImage(app, rawMatrix)
        if size(rawMatrix, 3) == 3
            rawMatrix = rgb2gray(rawMatrix);
        end
        normImg = im2double(rawMatrix);

        [rows, cols] = size(normImg);
        borderMask = false(rows, cols);
        borderMask(round(rows*0.08):round(rows*0.95), round(cols*0.05):round(cols*0.88)) = true;
    
        app.BorderMask = borderMask;
    end

        function imgSource = matrixToImageSource(~, imgMatrix)
            % Converts a double matrix [0, 1] into a temporary file path compatible with uiimage
            tempPath = [tempname, '.png'];
            imwrite(imgMatrix, tempPath);
            imgSource = tempPath;
        end

        function rgbOverlay = createBoundaryOverlay(~, baseImg, mask)
            % Draws yellow boundary outlines onto an RGB representation matrix
            rgbOverlay = repmat(baseImg, [1, 1, 3]);
            boundaries = bwperim(mask);
            
            % Set yellow pixels (R=1, G=1, B=0) on perimeter edges
            rChannel = rgbOverlay(:,:,1); rChannel(boundaries) = 1.0; rgbOverlay(:,:,1) = rChannel;
            gChannel = rgbOverlay(:,:,2); gChannel(boundaries) = 1.0; rgbOverlay(:,:,2) = gChannel;
            bChannel = rgbOverlay(:,:,3); bChannel(boundaries) = 0.0; rgbOverlay(:,:,3) = bChannel;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangeImageButton, UploadImageButton, 
        % ...and 1 other component
        function UploadImageButtonPushed(app, event)
        % Configure acceptable graphic extensions
            fileFilters = {'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'};
    
            % Open user navigation window
            [filename, pathname] = uigetfile(fileFilters, 'Select an Image');
    
            % Verify a valid file was selected
            if ~isequal(filename,0) && ~isequal(pathname,0)
                fullFilePath = fullfile(pathname, filename);
                
                % Store file path in primary UI Image component
                app.Image.ImageSource = fullFilePath;
                
                % Read raw image matrix and normalize for backend processing
                rawMatrix = imread(fullFilePath);
                app.RawImageData = app.prepareRawImage(rawMatrix);
                
                % Update visibility
                app.UploadImageButton.Visible = 'off';
                app.ChangeImageButton.Visible = 'on';
                app.ProceedButton.Visible = 'on';
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Hide Previous Buttons and Main Preview Image
            app.ProceedButton.Visible = 'off';
            app.ChangeImageButton.Visible = 'off';
            app.Image.Visible = 'off';
            
            % Show Processing UI components
            app.ImageProcessed.Visible = 'on';
            app.ImageRaw.Visible = 'on';
            app.ImageNoiseReduced.Visible = 'on';
            app.ImageAfterSegmentation.Visible = 'on';
            app.UploadImageButton_2.Visible = 'on';
            app.EditEvaluationButton.Visible = 'on';

            % Execute the automated processing pipeline
            app.executeAutoPipeline();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAfterSegmentation = uiimage(app.UIFigure);
            app.ImageAfterSegmentation.Visible = 'off';
            app.ImageNoiseReduced = uiimage(app.UIFigure);
            app.ImageNoiseReduced.Visible = 'off';
            app.ImageRaw = uiimage(app.UIFigure);
            app.ImageRaw.Visible = 'off';
            app.ImageProcessed = uiimage(app.UIFigure);
            app.ImageProcessed.Visible = 'off';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Visible = 'off';
            app.ProceedButton.Text = 'Proceed';
            app.ChangeImageButton = uibutton(app.UIFigure, 'push');
            app.ChangeImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.ChangeImageButton.Visible = 'off';
            app.ChangeImageButton.Text = 'Change Image';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Image = uiimage(app.UIFigure);
            app.UploadImageButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton_2.Visible = 'off';
            app.UploadImageButton_2.Text = 'Upload Image';
            app.EditEvaluationButton = uibutton(app.UIFigure, 'push');
            app.EditEvaluationButton.Visible = 'off';
            app.EditEvaluationButton.Text = 'Edit Evaluation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end